classdef RunRawAnalysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        config                    matlab.ui.container.Panel
        rawAnalyzerConfig         matlab.ui.control.EditField
        rawAnalyzerLabel          matlab.ui.control.Label
        browseRawAnalyzer         matlab.ui.control.Button
        cookedAnalyzerConfig      matlab.ui.control.EditField
        cookedAnalyzerLabel       matlab.ui.control.Label
        browseCookedAnalyzer      matlab.ui.control.Button
        dataAccessConfig          matlab.ui.control.EditField
        dataAccessLabel           matlab.ui.control.Label
        browseDataAccessor        matlab.ui.control.Button
        settingsConfigFile        matlab.ui.control.EditField
        settingsLabel             matlab.ui.control.Label
        browseSettingsConfigFile  matlab.ui.control.Button
        batchFolder               matlab.ui.control.EditField
        batchLocationLabel        matlab.ui.control.Label
        browseBatchFolder         matlab.ui.control.Button
        run                       matlab.ui.control.Button
    end

    
    methods (Access = private)
        function filePath = browseFile(app, path, label)
            newPath = path(1:find(path == '\', 1, 'Last'));
            if isempty(newPath) || isempty(regexp(newPath, '^[a-zA-Z]\:\\'))
                newPath = [pwd '\' newPath];
            end
            
            if newPath(end) ~= '\'
                newPath = [newPath '\'];
            end
            
            newPath = [newPath '*.*'];
            [fileName,newPath,~] = uigetfile(newPath, label);
            if isempty(fileName) || (length(fileName) == 1 && fileName == 0)
                filePath = path;
            else
                filePath = [newPath, fileName];
            end
        end
        
        function isValid = setConfigElement(app, textBox, setMethod)
            configPath = textBox.Value;
            if isempty(regexp(configPath, '^[a-zA-Z]\:\\', 'once'))
                configPath = [pwd '\' configPath];
            end
            
            try
                setMethod(configPath);
                textBox.BackgroundColor = rgb('White');
                isValid = true;
            catch ex
                textBox.BackgroundColor = rgb('Red');
                isValid = false;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function RunRawAnalysis_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to RunRawAnalysis (see VARARGIN)
            ForSDATApp.ensureAppLoaded();
            
            
%             % Choose default command line output for RunRawAnalysis
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             initialize_gui(hObject, handles, false);
        end

        % Button pushed function: browseBatchFolder
        function browseBatchFolder_Callback(app, event)
            % hObject    handle to browseBatchFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            folderPath = uigetdir(app.batchFolder.Value, 'Open F-D Curves Batch');
            if ~isempty(folderPath) && ~(length(folderPath) == 1 && folderPath == 0)
                app.batchFolder.Value = folderPath;
            end
        end

        % Button pushed function: browseCookedAnalyzer
        function browseCookedAnalyzer_Callback(app, event)
            % hObject    handle to browseCookedAnalyzer (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            filePath = browseFile(app, app.cookedAnalyzerConfig.Value, 'Browse Cooked Data Analyzer');
            app.cookedAnalyzerConfig.Value = filePath;
        end

        % Button pushed function: browseDataAccessor
        function browseDataAccessor_Callback(app, event)
            % hObject    handle to browseDataAccessor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filePath = browseFile(app, app.dataAccessConfig.Value, 'Browse Data Accessor');
            app.dataAccessConfig.Value = filePath;
        end

        % Button pushed function: browseRawAnalyzer
        function browseRawAnalyzer_Callback(app, event)
            % hObject    handle to browseRawAnalyzer (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filePath = browseFile(app, app.rawAnalyzerConfig.Value, 'Browse Raw Data Analyzer');
            app.rawAnalyzerConfig.Value = filePath;
        end

        % Button pushed function: browseSettingsConfigFile
        function browseSettingsConfigFile_Callback(app, event)
            % hObject    handle to browseSettingsConfigFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filePath = browseFile(app, app.settingsConfigFile.Value, 'Browse Settings Config File');
            app.settingsConfigFile.Value = filePath;
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % hObject    handle to run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ctrl = App.current.getController('ForceSpecAnalysisController');
            ctrl.setAnalyzedSegment('retract');
            
            isSetupOK = true;
            
            isSetupOK = isSetupOK && setConfigElement(app, app.rawAnalyzerConfig, @(cfg) ctrl.setRawAnalyzer(cfg));
            isSetupOK = isSetupOK && setConfigElement(app, app.cookedAnalyzerConfig, @(cfg) ctrl.setCookedAnalyzer(cfg));
            isSetupOK = isSetupOK && setConfigElement(app, app.dataAccessConfig, @(cfg) ctrl.setDataAccessor(cfg));
            isSetupOK = isSetupOK && setConfigElement(app, app.settingsConfigFile, @(cfg) ctrl.setSettings(cfg));
            
            if isSetupOK
                batchPath = strip(app.batchFolder.Value);
                if ~isempty(batchPath)
                    ctrl.dataAccessor.batchPath = batchPath;
                end
            
                batchanalysis;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'RunRawAnalysis';
            app.config = uipanel(app.figure1);
            app.config.Title = 'Analysis Configuration ';
            app.rawAnalyzerConfig = uieditfield(app.config, 'text');
            app.rawAnalyzerConfig.Value = '.\Data Analyzer\Application\SimpleGUI\Default Settings\rawAnalyzer.xml';
            app.rawAnalyzerLabel = uilabel(app.config);
            app.rawAnalyzerLabel.Text = 'Raw Analysis Configuration File:';
            app.browseRawAnalyzer = uibutton(app.config, 'push');
            app.browseRawAnalyzer.ButtonPushedFcn = createCallbackFcn(app, @browseRawAnalyzer_Callback, true);
            app.browseRawAnalyzer.Text = 'Browse...';
            app.cookedAnalyzerConfig = uieditfield(app.config, 'text');
            app.cookedAnalyzerConfig.Value = '.\Data Analyzer\Application\SimpleGUI\Default Settings\cookedAnalyzer.xml';
            app.cookedAnalyzerLabel = uilabel(app.config);
            app.cookedAnalyzerLabel.Text = 'Cooked Analysis Configuration File:';
            app.browseCookedAnalyzer = uibutton(app.config, 'push');
            app.browseCookedAnalyzer.ButtonPushedFcn = createCallbackFcn(app, @browseCookedAnalyzer_Callback, true);
            app.browseCookedAnalyzer.Text = 'Browse...';
            app.dataAccessConfig = uieditfield(app.config, 'text');
            app.dataAccessConfig.Value = '.\Data Analyzer\Application\SimpleGUI\Default Settings\jpkDataLoader.xml';
            app.dataAccessLabel = uilabel(app.config);
            app.dataAccessLabel.Text = 'Data Accessor Configuration File:';
            app.browseDataAccessor = uibutton(app.config, 'push');
            app.browseDataAccessor.ButtonPushedFcn = createCallbackFcn(app, @browseDataAccessor_Callback, true);
            app.browseDataAccessor.Text = 'Browse...';
            app.settingsConfigFile = uieditfield(app.config, 'text');
            app.settingsConfigFile.Value = '.\Data Analyzer\Application\SimpleGUI\Default Settings\defaultSettings.xml';
            app.settingsLabel = uilabel(app.config);
            app.settingsLabel.Text = 'Measurement and Analysis Settings:';
            app.browseSettingsConfigFile = uibutton(app.config, 'push');
            app.browseSettingsConfigFile.ButtonPushedFcn = createCallbackFcn(app, @browseSettingsConfigFile_Callback, true);
            app.browseSettingsConfigFile.Text = 'Browse...';
            app.batchFolder = uieditfield(app.config, 'text');
            app.batchLocationLabel = uilabel(app.config);
            app.batchLocationLabel.Text = 'Force Spectroscopy Batch Folder:';
            app.browseBatchFolder = uibutton(app.config, 'push');
            app.browseBatchFolder.ButtonPushedFcn = createCallbackFcn(app, @browseBatchFolder_Callback, true);
            app.browseBatchFolder.Text = 'Browse...';
            app.run = uibutton(app.figure1, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Text = 'Run Data Analysis';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RunRawAnalysis_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @RunRawAnalysis_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end